classdef se_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainPanel                      matlab.ui.container.Panel
        EndlessLabel                   matlab.ui.control.Label
        EndlessLamp                    matlab.ui.control.Lamp
        LampLabel                      matlab.ui.control.Label
        ResultsPanel                   matlab.ui.container.Panel
        WinPanel                       matlab.ui.container.Panel
        WarningYoumightwinbigLabel     matlab.ui.control.Label
        EndlessButton                  matlab.ui.control.Button
        StartOverButton_2              matlab.ui.control.Button
        WinLabel                       matlab.ui.control.Label
        ContinueButton                 matlab.ui.control.Button
        StartOverButton                matlab.ui.control.Button
        ResultsLabel                   matlab.ui.control.Label
        HintRevealPButton              matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        ImagesourceJeremyDoanmeLabel   matlab.ui.control.Label
        YouareanengineerLabel          matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        EnteranswerEditField           matlab.ui.control.NumericEditField
        EnteranswerEditFieldLabel      matlab.ui.control.Label
        EnterwagerEditField            matlab.ui.control.NumericEditField
        EnterwagerEditFieldLabel       matlab.ui.control.Label
        ScoreLabel                     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        StartPanel                     matlab.ui.container.Panel
        TestVersionLabel               matlab.ui.control.Label
        VersionLabel                   matlab.ui.control.Label
        ClicktobeginButton             matlab.ui.control.Button
        RulesLabel                     matlab.ui.control.Label
        DisclaimergamblinginreallifeisnotencouragedLabel  matlab.ui.control.Label
        StructuralEngineeringFunLabel  matlab.ui.control.Label
    end

% Algorithm:
% 1. User press "Click to begin" --done
% 2. Load Main Panel --done
% 3. Initialize score --done
% 4. Initialize variables --done
% 5. Select caseb --done
% disp P = ? for all cases
% if case = 1
%     disp L = ? 
% case = 2
%     disp a = ?
% case = 3
%     disp b = ?

% --Math--
% I = (a * b ^3)/12
% Leff = 2L

% P = pi ^2 * E * I/Leff^2
% = pi ^2 * E * (a * b ^3)/12/4L^2
% = pi^2 * E * (a * b ^3)/48L^2

% app.a = app.P * 48 * app.L^2 / (pi^2 * app.E * app.b^3);
% app.b = (app.P * 48 * app.L^2 / (pi^2 * app.E * app.a)) ^ (1/3);
% app.L = sqrt((pi^2 * app.E * app.a * app.b^3)/(48 * app.P));


% 5.5. User press "Hint", disp P value --done
% 6. User enter wager, enter guess --done
% 7. User press "Next" --done
% 8. Evaluate answer --done
% if guess +- 10 = answer
%     score = score + wager
%     Select case
% else 
%     score = score - wager
%     Select case
% if score < 0
%     disp "Bankrupt!"
%     Load Start Panel
%     if score >= 500
%         disp "You win!"
%         Load Start panel or continue?

% Endless mode next --done
% Random events --> 1/3 chance of random event, one of 4 events
% if endless mode
% event = randi([1,15])
%   no random event: 6-10
%   event 1, 2: (2/5 chance)
%   gain 200 points
%   event 3: (1/5 chance)
%   gain 0-300 points
%   event 4: (1/5 chance)
%   lose 100 points
%   event 5: (1/5 chance)
%   choose: gain 100 points or win/lose 500 points

% Remaining tasks:
% Add text to:
%     - Random events done
%     - Game over screen done
% Add "All in" button done
% Add fade in effects
% Add graphics

    % Score
    properties (Access = private)
        Score % User score
        L % Length
        P % Load
        a % Width
        b % Height
        E % Young's Modulus
        Answer % User answer
        caseNum % Case number
        Status % Correct/Incorrect
        Endless % Select endless mode after reaching 1000 points
        EventPanel % Random Events
            titleEvent
            EventContinue
            EventOption1
            EventOption2
            EventDescription
        GameOverScreen
            titleGameOver
            GameOverButton
            GameOverLabel
        AllInButton
    end
    
    methods (Access = private)
        
        function InitializeScore(app)
            app.Score = 100;
            % Update the Score Label text
            app.ScoreLabel.Text = ['Coins: ', num2str(app.Score)];
            app.EnterwagerEditField.Value = 0;
        end
        
        function RandomizeParameters(app)
             app.L = 1 + (5-1) * rand;
             app.P = randi([1,500]);   
             app.a = 0.001 + (1.5-1) * rand;
             app.b = 0.001 + (1.5-1) * rand;
             app.E = 100e6;
             app.EnteranswerEditField.Value = 0;
        end
        
        function RandomCase(app)
            
            app.caseNum = randi([1,3]);
            % Fill table with parameter values
            app.UITable.Data = {app.L, app.P, app.a, app.b, app.E}; 

            % Clear one of the variables depending on the case
            % P is hidden by default to add difficulty
            % If "hint" is pressed, reveal P
            switch app.caseNum
                case 1
                    app.L = sqrt((pi^2 * app.E * app.a * app.b^3)/(48 * app.P));
                    app.UITable.Data{1, 1} = '???'; % Hide L
                    app.UITable.Data{1, 2} = '???'; % Hide P
                case 2
                    app.a = app.P * 48 * app.L^2 / (pi^2 * app.E * app.b^3);
                    app.UITable.Data{1, 3} = '???'; % Hide a
                    app.UITable.Data{1, 2} = '???'; % Hide P
                case 3
                    app.b = (app.P * 48 * app.L^2 / (pi^2 * app.E * app.a)) ^ (1/3);
                    app.UITable.Data{1, 4} = '???'; % Hide b
                    app.UITable.Data{1, 2} = '???'; % Hide P
            end
        end
        
        function EvalAnswer(app)
            % Read user answer
            userAnswer = app.EnteranswerEditField.Value;
        
            % Evaluate answer based on case number
            switch app.caseNum
                case 1
                    if abs(userAnswer - app.L) <= 1
                        app.Score = app.Score + app.EnterwagerEditField.Value;
                        app.Status = "Correct";
                    else
                        app.Score = app.Score - app.EnterwagerEditField.Value;
                        app.Status = "Incorrect";
                    end
                case 2
                    if abs(userAnswer - app.a) <= 0.01
                        app.Score = app.Score + app.EnterwagerEditField.Value;
                        app.Status = "Correct";
                    else
                        app.Score = app.Score - app.EnterwagerEditField.Value;
                        app.Status = "Incorrect";
                    end
                case 3
                    if abs(userAnswer - app.b) <= 0.01
                        app.Score = app.Score + app.EnterwagerEditField.Value;
                        app.Status = "Correct";
                    else
                        app.Score = app.Score - app.EnterwagerEditField.Value;
                        app.Status = "Incorrect";
                    end
            end
        
            % Update score label
            app.ScoreLabel.Text = ['Coins: ', num2str(app.Score)];
        
            % Update wager limits
            app.EnterwagerEditField.Limits = [0, app.Score];
            
            % Hide the All In button
            app.AllInButton.Visible = "off";

            % Open results panel
            app.ResultsPanel.Visible = "on";
            
            switch app.caseNum
                case 1
                    app.ResultsLabel.Text = sprintf("%s! " + ...
                        "\n\nThe correct answer is %.3f." + ...
                        "\nYour current balance is %d." + ...
                        "\n\nDo you want to start over or continue?", ...
                        app.Status, app.L, app.Score);
                case 2
                    app.ResultsLabel.Text = sprintf("%s! " + ...
                        "\n\nThe correct answer is %.3f." + ...
                        "\nYour current balance is %d." + ...
                        "\n\nDo you want to start over or continue?", ...
                        app.Status, app.a, app.Score);
                case 3
                    app.ResultsLabel.Text = sprintf("%s! " + ...
                        "\n\nThe correct answer is %.3f." + ...
                        "\nYour current balance is %d." + ...
                        "\n\nDo you want to start over or continue?", ...
                        app.Status, app.b, app.Score);
            end
            
            % Reach 500 points
            if app.Endless == "off"
                if app.Score >= 500 % CHANGE THIS BACK TO 1000 FOR FINAL VERSION
                    app.WinPanel.Visible = "on";
                    app.WinLabel.Visible = "on";
                    app.AllInButton.Visible = "off";
                end
            end
        end
        
        % Random events
        function RandomEvent(app)
            EventNum = randi([1,15]);
        
            % Create a new panel
            app.EventPanel = uipanel(app.MainPanel, 'Title', '', ...
                'Position', [180, 200, 500, 350], 'BackgroundColor', 'white');
            
            % Add a label as a title inside the panel
            app.titleEvent = uicontrol('Parent', app.EventPanel, 'Style', 'text', ...
                'String', 'Random Event', ...
                'FontName', 'Garamond', ...
                'FontWeight', 'bold', ...    % Make the text bold
                'FontSize', 14, ...          % Set the font size
                'HorizontalAlignment', 'center', ... % Center the text
                'Units', 'normalized', ...   % Use normalized units for positioning
                'Position', [0, 0.9, 1, 0.1], ... % Position the label at the top
                'BackgroundColor', [0.93,0.69,0.13]); % Golden background
        
            % Continue button (for Eventnum 1-3)
            app.EventContinue = uibutton(app.EventPanel, 'push');
            app.EventContinue.ButtonPushedFcn = createCallbackFcn(app, @EventContinueFcn, true);
            app.EventContinue.BackgroundColor = [1 1 1];
            app.EventContinue.FontName = 'Garamond';
            app.EventContinue.FontSize = 14;
            app.EventContinue.FontWeight = 'bold';
            app.EventContinue.Position = [150 30 200 50];
            app.EventContinue.Text = 'Continue';
        
            % Options (for Eventnum 4)
            % Create Option 1
            app.EventOption1 = uibutton(app.EventPanel, 'push');
            app.EventOption1.ButtonPushedFcn = createCallbackFcn(app, @EventOption1Fcn, true);
            app.EventOption1.BackgroundColor = [1 1 1];
            app.EventOption1.FontName = 'Garamond';
            app.EventOption1.FontSize = 14;
            app.EventOption1.FontWeight = 'bold';
            app.EventOption1.Position = [50 30 150 50];
            app.EventOption1.Text = 'Take 100 coins!';
        
            % Create Option 2
            app.EventOption2 = uibutton(app.EventPanel, 'push');
            app.EventOption2.ButtonPushedFcn = createCallbackFcn(app, @EventOption2Fcn, true);
            app.EventOption2.BackgroundColor = [1 1 1];
            app.EventOption2.FontName = 'Garamond';
            app.EventOption2.FontSize = 14;
            app.EventOption2.FontWeight = 'bold';
            app.EventOption2.Position = [300 30 150 50];
            app.EventOption2.Text = 'Take the chance!'; 

            % Event flavor text

            % Gain 200 points
            EventText1 = [
              "<html><p style='text-align: center;'>You found someone's wallet on the ground. <br><br>You returned it to its owner and received a reward. (+200 coins)</p></html>";
              "<html><p style='text-align: center;'>You've been working very hard and impressed the higher-ups (1). <br><br>You received a bonus! (+200 coins)</p></html>";
              "<html><p style='text-align: center;'>Your pet cat returned from a long day of hunting. <br><br>They brought back 200 coins??</p></html>"
            ];
            
            % Gain 0-300 points
            EventText2 = [
                "<html><p style='text-align:center;'>You started a side business of selling homegrown tomatoes. <br><br>They fetch a decent price. (+0-300 coins)</p></html>";
                "<html><p style='text-align:center;'>You started a Youtube channel for solving engineering problems. <br><br>Many of your subscribers use adblockers. (+0-300 coins)</p></html>";
                "<html><p style='text-align:center;'>The company received a commission from a shady client. <br><br>Did we actually get paid? (+0-300 coins)</p></html>"
            ];

            % Lose 100 points
            EventText3 = [
                "<html><p style='text-align:center;'>You got jumped! You said 'Nah I would win.' <br><br>...But you lost anyways. (-100 coins)</p></html>";
                "<html><p style='text-align:center;'>The CEO got caught embezzling funds! <br><br>My paycheck... (-100 coins)</p></html>";
                "<html><p style='text-align:center;'>The new intern made a calculation mistake. <br><br>This cost the company a bit. (-100 coins)</p></html>"
            ];

            % Choose between +100 coins or +/- 500 coins
            EventText4 = [
                "<html><p style='text-align:center;'>Your cat encouraged you to invest into stocks. <br><br>Cash out now, or take the risk? (+100 coins or +/- 500 coins)";
                "<html><p style='text-align:center;'>The CEO consulted you about hiring some subcontractors. <br><br>Decline or hire them for this project? (+100 coins or +/- 500 coins)";
                "<html><p style='text-align:center;'>You couldn't resist the cruise ship casino. <br><br>Will you risk your vacation? (+100 coins or +/- 500 coins)"
            ];

            app.EventDescription = uilabel(app.EventPanel);
            app.EventDescription.WordWrap = 'off';
            app.EventDescription.FontName = 'Garamond';
            app.EventDescription.FontSize = 18;
            app.EventDescription.Interpreter = 'html';
            app.EventDescription.Position = [10 190 490 100];

                % Set the event description based on EventNum
                if EventNum == 1 || EventNum == 2
                    % Randomly select one of the flavor texts for Event 1
                    app.EventDescription.Text = EventText1{randi(length(EventText1))};
                else
                    if EventNum == 3
                        amtgained = randi([0, 300]);
                        app.EventDescription.Text = EventText2{randi(length(EventText2))};
                    elseif EventNum == 4
                        app.EventDescription.Text = EventText3{randi(length(EventText3))};
                    elseif EventNum == 5
                        app.EventDescription.Text = EventText4{randi(length(EventText4))};
                    end
                end

            % Callback for Event Continue
            function EventContinueFcn(app, event)
                if EventNum == 1 || EventNum == 2
                    % Event 1 or 2: gain 200 points
                    app.Score = app.Score + 200;
                elseif EventNum == 3
                    % Event 3: gain 0-300 points
                    app.Score = app.Score + amtgained;
                elseif EventNum == 4 
                    % Event 4: lose 100 points
                    app.Score = app.Score - 100;
                end
                app.ScoreLabel.Text = ['Coins: ', num2str(app.Score)];
                app.EventPanel.Visible = "off";
                ContinueButtonFcn(app);
            end
        
            % Callback for EventOption1
            function EventOption1Fcn(app, event)
                % Code to handle when "Take 100 coins!" is pressed
                app.Score = app.Score + 100;
                app.ScoreLabel.Text = ['Coins: ', num2str(app.Score)];
                
                % Hide the EventPanel or proceed as needed
                app.EventPanel.Visible = "off";
                ContinueButtonFcn(app);
            end
        
            % Callback for EventOption2
            function EventOption2Fcn(app, event)
                % Code to handle when "Take the chance!" is pressed
                chanceResult = randi([0, 1]); % Random chance: 0 or 1
                if chanceResult == 1
                    app.Score = app.Score + 500;
                else
                    app.Score = app.Score - 500;
                end
                app.ScoreLabel.Text = ['Coins: ', num2str(app.Score)];
                
                % Hide the EventPanel or proceed as needed
                app.EventPanel.Visible = "off";
                ContinueButtonFcn(app);
            end
        
            % Set initial visibility
            app.EventPanel.Visible = "off";
            app.EventContinue.Visible = "off";
            app.EventOption1.Visible = "off";
            app.EventOption2.Visible = "off";
        
            % Display the event and relevant buttons
            if EventNum == 1 || EventNum == 2 || EventNum == 3 || EventNum == 4
                % Show the panel and continue button
                app.EventPanel.Visible = "on";
                app.EventContinue.Visible = "on";
            elseif EventNum == 5
                % Event 5: choose: gain 100 points or win/lose 500 points
                app.EventPanel.Visible = "on";
                app.EventOption1.Visible = "on";
                app.EventOption2.Visible = "on";
            end
        end
        
        function AllInButtonPushed(app, event)
            app.EnterwagerEditField.Value = app.Score;
        end

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClicktobeginButton
        function ClicktobeginButtonPushed(app, event)
            % Initialize the score
            InitializeScore(app);
            
            % Initialize variables
            RandomizeParameters(app);

            % Select random case
            RandomCase(app);

            % Initialize panels
            app.StartPanel.Visible = "off";
            app.MainPanel.Visible = "on";

            % Set the wager edit field limits
            app.EnterwagerEditField.Limits = [0, app.Score];

            % Endless mode off
            app.Endless = "off";

            % Create All In! button
            app.AllInButton = uibutton(app.MainPanel, 'push');
            app.AllInButton.ButtonPushedFcn = createCallbackFcn(app, @AllInButtonPushed, true);
            app.AllInButton.BackgroundColor = [1 1 1];
            app.AllInButton.FontName = 'Garamond';
            app.AllInButton.FontSize = 14;
            app.AllInButton.FontWeight = 'bold';
            app.AllInButton.Position = [205 40 100 22]; 
            app.AllInButton.Text = 'All or nothing!';
            app.AllInButton.Visible = 'on';
      
        end

        % Button pushed function: HintRevealPButton
        function Hint(app, event)
            app.UITable.Data{1, 2} = app.P; % Reveal P
        end

        % Button pushed function: NextButton
        function EvalAnswerFcn(app, event)
            EvalAnswer(app);
            app.AllInButton.Visible = 'off';
        end

        % Button pushed function: ContinueButton
        function ContinueButtonFcn(app, event)
            app.ResultsPanel.Visible = "off";
            app.WinPanel.Visible = "off";
            app.WinLabel.Visible = "off";
            app.EventPanel.Visible = "off";
            app.AllInButton.Visible = "off";
            
            % Initialize variables
            RandomizeParameters(app);
        
            % Select random case
            RandomCase(app);
        
            % Initialize panels
            app.StartPanel.Visible = "off";
            app.MainPanel.Visible = "on";

            % Reset wager field
            app.EnterwagerEditField.Value = 0;

            % All In button
            app.AllInButton = uibutton(app.MainPanel, 'push');
            app.AllInButton.ButtonPushedFcn = createCallbackFcn(app, @AllInButtonPushed, true);
            app.AllInButton.BackgroundColor = [1 1 1];
            app.AllInButton.FontName = 'Garamond';
            app.AllInButton.FontSize = 14;
            app.AllInButton.FontWeight = 'bold';
            app.AllInButton.Position = [205 40 100 22]; 
            app.AllInButton.Text = 'All or nothing!';

            % Endless mode
            if app.Endless == "on"
                app.EventPanel.Visible = "off";
                app.EventContinue.Visible = "off";
                app.EventOption1.Visible = "off";
                app.EventOption2.Visible = "off";
                RandomEvent(app);
            end
        
            % Check for zero/negative coins and show game over screen if needed
            if app.Score <= 0
                app.MainPanel.Visible = "off";
                app.AllInButton.Visible = "off";
                
                % Create a new panel
                app.GameOverScreen = uipanel(app.UIFigure, 'Title', '', ...
                    'Position', [150, 150, 500*1.25, 350*1.25], 'BackgroundColor', 'white');
                
                % Add a label as a title inside the panel
                app.titleGameOver = uicontrol('Parent', app.GameOverScreen, 'Style', 'text', ...
                    'String', 'GAME OVER', ...
                    'FontName', 'Garamond', ...
                    'FontWeight', 'bold', ...    % Make the text bold
                    'FontSize', 18, ...          % Set the font size
                    'HorizontalAlignment', 'center', ... % Center the text
                    'Units', 'normalized', ...   % Use normalized units for positioning
                    'Position', [0, 0.9, 1, 0.1], ... % Position the label at the top
                    'BackgroundColor', 'red',... % Golden background
                    'ForegroundColor', 'white'); % White font

                % Game over flavor text
                app.GameOverLabel = uilabel(app.GameOverScreen);
                app.GameOverLabel.FontName = 'Garamond';
                app.GameOverLabel.FontSize = 18;
                app.GameOverLabel.Position = [10 100 600 400];
                app.GameOverLabel.Interpreter = 'html';
                GameOverText = [
                    "<html><p style='text-align: center;'>The company went bankrupt! You are unable to pay the bills.<br><br>Your cat got fed up and left you for a better owner.</p></html>";
                    "<html><p style='text-align: center;'>You spent too much money on gacha games! <br><br>You can no longer afford food and utilities.</p></html>";
                    "<html><p style='text-align: center;'>You tried to buy everything in bulk! <br><br>You are now a T*mu victim.</p></html>"
                    ];
                app.GameOverLabel.Text = GameOverText{randi(length(GameOverText))};

                % Start over button
                app.GameOverButton = uibutton(app.GameOverScreen, 'push');
                app.GameOverButton.ButtonPushedFcn = @(btn,event) StartOverFcn(app);  
                app.GameOverButton.BackgroundColor = 'red';
                app.GameOverButton.FontName = 'Garamond';
                app.GameOverButton.FontSize = 14;
                app.GameOverButton.FontWeight = 'bold';
                app.GameOverButton.FontColor = 'white';
                app.GameOverButton.Position = [210 50 200 50];
                app.GameOverButton.Text = 'Start Over';
                
                app.GameOverScreen.Visible = "on";
        
                % Set wager limits to [0, 0] to prevent further input
                app.EnterwagerEditField.Limits = [0, 0];
            else
                % Update wager limits only if score is positive
                app.EnterwagerEditField.Limits = [0, app.Score];
            end
        
        end

        % Button pushed function: StartOverButton, StartOverButton_2
        function StartOverFcn(app, event)
            app.ResultsPanel.Visible = "off";
            app.MainPanel.Visible = "off";
            app.StartPanel.Visible = "on";
            app.EndlessLabel.Visible = "off";
            app.EndlessLamp.Visible = "off";
            app.GameOverScreen.Visible = "off";
            app.AllInButton.Visible = "off";
            app.Endless = "off";
        end

        % Button pushed function: EndlessButton
        function StartEndlessFcn(app, event)
            app.Endless = "on";
            ContinueButtonFcn(app);
            app.EndlessLabel.Visible = "on";
            app.EndlessLamp.Visible = "on";
            app.EndlessLamp.Color = "green";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartPanel = uipanel(app.UIFigure);
            app.StartPanel.Title = 'Start';
            app.StructuralEngineeringFunLabel = uilabel(app.StartPanel);
            app.StructuralEngineeringFunLabel.Text = 'Structural Engineering Fun';
            app.DisclaimergamblinginreallifeisnotencouragedLabel = uilabel(app.StartPanel);
            app.DisclaimergamblinginreallifeisnotencouragedLabel.Interpreter = 'html';
            app.DisclaimergamblinginreallifeisnotencouragedLabel.Text = {' Made by Jeremy Doan'; ''; 'Disclaimer: gambling in real life is not encouraged!'};
            app.RulesLabel = uilabel(app.StartPanel);
            app.RulesLabel.Text = {'Rules:'; '- Given a random scenario'; '- Guess the missing dimension'; '- Place your wager (between 0 and your current score)'; '- Try to get to 500 coins'; '- Have fun :)'};
            app.ClicktobeginButton = uibutton(app.StartPanel, 'push');
            app.ClicktobeginButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktobeginButtonPushed, true);
            app.ClicktobeginButton.Text = 'Click to begin';
            app.VersionLabel = uilabel(app.StartPanel);
            app.VersionLabel.Text = 'v 1.1.1';
            app.TestVersionLabel = uilabel(app.StartPanel);
            app.TestVersionLabel.Visible = 'off';
            app.TestVersionLabel.Text = 'Test Version: You can complete the main campaign after just 100 coins.';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'Main';
            app.MainPanel.Visible = 'off';
            app.Image = uiimage(app.MainPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'SmartSelect_20240804_235331_Samsung Notes.png');
            app.ScoreLabel = uilabel(app.MainPanel);
            app.ScoreLabel.Tag = 'Score';
            app.ScoreLabel.Text = '';
            app.EnterwagerEditFieldLabel = uilabel(app.MainPanel);
            app.EnterwagerEditFieldLabel.Text = 'Enter wager:';
            app.EnterwagerEditField = uieditfield(app.MainPanel, 'numeric');
            app.EnterwagerEditField.Limits = [0 Inf];
            app.EnterwagerEditField.Placeholder = '0';
            app.EnteranswerEditFieldLabel = uilabel(app.MainPanel);
            app.EnteranswerEditFieldLabel.Text = 'Enter answer:';
            app.EnteranswerEditField = uieditfield(app.MainPanel, 'numeric');
            app.EnteranswerEditField.Limits = [0 Inf];
            app.EnteranswerEditField.Placeholder = '0';
            app.NextButton = uibutton(app.MainPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @EvalAnswerFcn, true);
            app.NextButton.Text = 'Next';
            app.YouareanengineerLabel = uilabel(app.MainPanel);
            app.YouareanengineerLabel.Interpreter = 'html';
            app.YouareanengineerLabel.Text = {'You are an engineer in a new startup company. You are currently designing for compressive loads. To save money, your'; 'company has contracted a third party manufacturer. Unfortunately, this manufactuer is lousy and provides components of'; 'unreliable dimensions. You must<b> figure out the missing dimension</b> given the other relevant parameters. Your company and your livelihood are on the line! <em>(Assume buckling does not occur. Assume E is always 100e6.) </em>'};
            app.ImagesourceJeremyDoanmeLabel = uilabel(app.MainPanel);
            app.ImagesourceJeremyDoanmeLabel.Text = 'Image source: Jeremy Doan (me)';
            app.UITable = uitable(app.MainPanel);
            app.UITable.ColumnName = {'L'; 'P'; 'a'; 'b'; 'E'};
            app.UITable.RowName = {'Value'};
            app.HintRevealPButton = uibutton(app.MainPanel, 'push');
            app.HintRevealPButton.ButtonPushedFcn = createCallbackFcn(app, @Hint, true);
            app.HintRevealPButton.Text = {'Hint?'; '(Reveal P)'};
            app.ResultsPanel = uipanel(app.MainPanel);
            app.ResultsPanel.Visible = 'off';
            app.ResultsLabel = uilabel(app.ResultsPanel);
            app.ResultsLabel.Text = '';
            app.StartOverButton = uibutton(app.ResultsPanel, 'push');
            app.StartOverButton.ButtonPushedFcn = createCallbackFcn(app, @StartOverFcn, true);
            app.StartOverButton.Text = 'Start Over';
            app.ContinueButton = uibutton(app.ResultsPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonFcn, true);
            app.ContinueButton.Text = 'Continue';
            app.WinPanel = uipanel(app.ResultsPanel);
            app.WinPanel.Visible = 'off';
            app.WinLabel = uilabel(app.WinPanel);
            app.WinLabel.Visible = 'off';
            app.WinLabel.Text = {'Congratulations! You reached 1000 coins and your company is thriving. '; ''; 'However, the CEO made some poor financial decisions...'};
            app.StartOverButton_2 = uibutton(app.WinPanel, 'push');
            app.StartOverButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartOverFcn, true);
            app.StartOverButton_2.Text = 'Start Over';
            app.EndlessButton = uibutton(app.WinPanel, 'push');
            app.EndlessButton.ButtonPushedFcn = createCallbackFcn(app, @StartEndlessFcn, true);
            app.EndlessButton.Text = 'Endless!!';
            app.WarningYoumightwinbigLabel = uilabel(app.WinPanel);
            app.WarningYoumightwinbigLabel.Text = 'Warning: You might win big ;)';
            app.LampLabel = uilabel(app.MainPanel);
            app.LampLabel.Text = '';
            app.EndlessLamp = uilamp(app.MainPanel);
            app.EndlessLamp.Visible = 'off';
            app.EndlessLabel = uilabel(app.MainPanel);
            app.EndlessLabel.Visible = 'off';
            app.EndlessLabel.Text = 'Endless';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = se_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end